classdef MyCircles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        CleanButton  matlab.ui.control.Button
        AddButton    matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Setup(app)
            axis(app.UIAxes, 10*[-1 1 -1 1])
            hold(app.UIAxes, "on");
            axis(app.UIAxes, "off");
            plot(app.UIAxes, 10*[-1 1 1 -1 -1], 10*[-1 -1 1 1 -1]);
        end

        % Button pushed function: AddButton
        function AddCircle(app, event)
            t = linspace(0, 2*pi, 100);
            x = cos(t); y = sin(t);
            cx = randi([-10 10]);
            cy = randi([-10 10]);
            r = 0.5 + 3 * rand;
            p = patch(app.UIAxes, cx + x*r, cy + y*r, [rand, rand, rand]);
            p.FaceAlpha = 0.5;
        end

        % Button pushed function: CleanButton
        function CleanCanvas(app, event)
            cla(app.UIAxes)
            plot(app.UIAxes, 10*[-1 1 1 -1 -1], 10*[-1 -1 1 1 -1]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddCircle, true);
            app.AddButton.Text = 'Add';
            app.CleanButton = uibutton(app.UIFigure, 'push');
            app.CleanButton.ButtonPushedFcn = createCallbackFcn(app, @CleanCanvas, true);
            app.CleanButton.Text = 'Clean';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyCircles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @Setup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end