classdef MyPebbles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        ClearButton  matlab.ui.control.Button
        AddButton    matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AddButton
        function AddPebbles(app, event)
            [x, y] = pebble(1.5, 0.2, 5, 100);
            cx = randi([-10 10]);
            cy = randi([-10 10]);
            r = 1 + 2*rand;
            p = patch(app.UIAxes, cs + r*x, cy + r*y, [rand, rand, rand]);
            p.FaceAlpha = 0.5;
            p.EdgeColor = "none";
            plot(app.UIAxes, 10*[-1 1 1 -1 -1], 10*[-1 -1 1 1 -1]);
        end

        % Button pushed function: ClearButton
        function Clear(app, event)
            cla(app.UIAxes);
            plot(app.UIAxes, 10*[-1 1 1 -1 -1], 10*[-1 -1 1 1 -1]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddPebbles, true);
            app.AddButton.Text = 'Add';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @Clear, true);
            app.ClearButton.Text = 'Clear';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MyPebbles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end