classdef audio_player < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        ListBox   matlab.ui.control.ListBox
        Label     matlab.ui.control.Label
        stop      matlab.ui.control.Button
        play      matlab.ui.control.Button
        pause     matlab.ui.control.Button
        UIAxes    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        y;
        Fs;
        player;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            [app.y, app.Fs] = audioread("hello_adele.mp3");
            app.player = audioplayer(app.y, app.Fs);

             % 안전하게 파형을 그리는 코드 (App Designer)
            if isempty(app.y) || isempty(app.Fs)
                uialert(app.UIFigure, '오디오가 로드되지 않았습니다.', '오류');
                return;
            end
            
            % y를 (N x C) 형태로 보장: 샘플이 행이면 전치
            if isrow(app.y)
                app.y = app.y.'; % 열로 만들기
            end
            
            % 샘플 수와 시간축 생성
            n = size(app.y,1);        % 샘플 수
            t = (0:n-1) / app.Fs;     % 길이 n
            
            % 길이 불일치 안전 검사
            if numel(t) ~= size(app.y,1)
                uialert(app.UIFigure, '시간축과 신호 길이가 일치하지 않습니다.', '오류');
                return;
            end
            
            % 그리기
            cla(app.UIAxes);
            if size(app.y,2) == 1
                plot(app.UIAxes, t, app.y, 'b');
                legend(app.UIAxes, 'Mono');
            else
                plot(app.UIAxes, t, app.y); % 각 채널을 자동으로 그립니다
                % 필요하면 legend을 채널 수에 맞게 설정
                ch = size(app.y,2);
                lg = arrayfun(@(k) sprintf('Channel %d',k), 1:ch, 'UniformOutput', false);
                legend(app.UIAxes, lg{:});
            end
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Waveform');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [t(1), t(end)]);

        end

        % Button pushed function: play
        function playPushed(app, event)
            if isempty(app.player)
                uialert(app.UIFigure, '먼저 노래를 선택하세요.', '오류');
                return;
            end

            resume(app.player);
        end

        % Button pushed function: pause
        function pausePushed(app, event)
            if ~isempty(app.player)
                pause(app.player);
            end
        end

        % Button pushed function: stop
        function stopPushed(app, event)
            if ~isempty(app.player)
                stop(app.player);
            end
        end

        % Clicked callback: ListBox
        function ListBoxClicked(app, event)

        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = app.ListBox.Value;
           
            if strcmp(value, 'Hello - Adele')
                filename = "hello_adele.mp3";

            elseif strcmp(value, 'Antifreeze - 백예린')
                filename = "Antifreeze.m4a";
    
            elseif strcmp(value, 'Snow -RHCP')
                filename = "Snow.m4a";

            elseif strcmp(value, 'Test Drive - 드래곤 길들이기')
                filename = "TestDrive.m4a";

            end

            [app.y, app.Fs] = audioread(filename);

             % 안전하게 파형을 그리는 코드 (App Designer)
            if isempty(app.y) || isempty(app.Fs)
                uialert(app.UIFigure, '오디오가 로드되지 않았습니다.', '오류');
                return;
            end
            
            % y를 (N x C) 형태로 보장: 샘플이 행이면 전치
            if isrow(app.y)
                app.y = app.y.'; % 열로 만들기
            end
            
            % 샘플 수와 시간축 생성
            n = size(app.y,1);        % 샘플 수
            t = (0:n-1) / app.Fs;     % 길이 n
            
            % 길이 불일치 안전 검사
            if numel(t) ~= size(app.y,1)
                uialert(app.UIFigure, '시간축과 신호 길이가 일치하지 않습니다.', '오류');
                return;
            end
            
            % 그리기
            cla(app.UIAxes);
            if size(app.y,2) == 1
                plot(app.UIAxes, t, app.y, 'b');
                legend(app.UIAxes, 'Mono');
            else
                plot(app.UIAxes, t, app.y); % 각 채널을 자동으로 그립니다
                % 필요하면 legend을 채널 수에 맞게 설정
                ch = size(app.y,2);
                lg = arrayfun(@(k) sprintf('Channel %d',k), 1:ch, 'UniformOutput', false);
                legend(app.UIAxes, lg{:});
            end
            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Amplitude');
            title(app.UIAxes, 'Waveform');
            grid(app.UIAxes, 'on');
            xlim(app.UIAxes, [t(1), t(end)]);

            app.player = audioplayer(app.y, app.Fs);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Waveform')
            xlabel(app.UIAxes, 'Time(s)')
            ylabel(app.UIAxes, 'Amplitude')
            zlabel(app.UIAxes, 'Z')
            app.pause = uibutton(app.UIFigure, 'push');
            app.pause.ButtonPushedFcn = createCallbackFcn(app, @pausePushed, true);
            app.pause.Icon = 'pause.png';
            app.pause.Text = '';
            app.play = uibutton(app.UIFigure, 'push');
            app.play.ButtonPushedFcn = createCallbackFcn(app, @playPushed, true);
            app.play.Icon = 'play.png';
            app.play.Text = '';
            app.stop = uibutton(app.UIFigure, 'push');
            app.stop.ButtonPushedFcn = createCallbackFcn(app, @stopPushed, true);
            app.stop.Icon = 'stop.png';
            app.stop.Text = '';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '음악 파일';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'Hello - Adele', 'Antifreeze - 백예린', 'Snow -RHCP', 'Test Drive - 드래곤 길들이기'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.ClickedFcn = createCallbackFcn(app, @ListBoxClicked, true);
            app.ListBox.Value = 'Hello - Adele';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_player

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end