classdef counter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        labelLabel  matlab.ui.control.Label
        Button_2    matlab.ui.control.Button
        Button      matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        count;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.count = 0;
            app.labelLabel.Text = num2str(app.count);
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.count = app.count + 1;
            app.count
            app.labelLabel.Text = num2str(app.count);
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.count = app.count -1;
            if app.count < 0
                app.count = 0;
            end
            app.labelLabel.Text = num2str(app.count);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = fullfile(pathToMLAPP, 'arrow_drop_down_circle_60dp_0000FF_FILL0_wght400_GRAD0_opsz48.png');
            app.Button.Interpreter = 'latex';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Icon = fullfile(pathToMLAPP, 'expand_circle_up_60dp_0000FF_FILL0_wght400_GRAD0_opsz48.png');
            app.Button_2.Text = '';
            app.labelLabel = uilabel(app.UIFigure);
            app.labelLabel.Text = 'label';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = counter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end